classdef interface1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MCQANSWERSHEETMARKINGSYSTEMLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        SelectReferanceAnswerSheetButton  matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        GiveAnswerButton         matlab.ui.control.Button
        TotalNumberOfQuestionsEditFieldLabel  matlab.ui.control.Label
        TotalNumberOfQuestions   matlab.ui.control.NumericEditField
        SelectAnswerSheetButton  matlab.ui.control.Button
        ans                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectReferanceAnswerSheetButton
        function select(app, event)
            [file, path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            global img;
            img = imread(fullfile(path,file));
            app.img = img;
            imshow(app.img,'parent',app.UIAxes);
        end

        % Button pushed function: SelectAnswerSheetButton
        function select_sheet(app, event)
            [file, path] = uigetfile('*.*');
             if isequal(file,0)
                figure(app.UIFigure);
                return;
             end
             figure(app.UIFigure);
            global img2;
            img2 = imread(fullfile(path,file));
            app.img2 = img2;
            imshow(app.img2,'parent',app.UIAxes2);
        end

        % Button pushed function: GiveAnswerButton
        function GiveAnswerButtonPushed(app, event)
            global img2;
            global img;
            numberOfQ = app.TotalNumberOfQuestions.Value;
            differentiated_image = imabsdiff(img, img2);
            
            % Convert the differentiated image to bw
            diffgray = rgb2gray(differentiated_image);
            diffbw = im2bw(diffgray);
            
            % Remove noise from the differentiated image
            %diffmedfil = medfilt2(diffbw);
            
            % Remove unnessacary edges from the differentiated image
            sel = strel('disk', 3);
            differode = imerode(diffbw, sel);
            
            % Count the objects in differentiated image
            [label count] = bwlabel(differode, 4);
            
            % Define total number of questions
            totalNumberOfQuestions = numberOfQ;
            
            % Calculate the marks
            noOfCorrectAnswers = totalNumberOfQuestions - (count/2);
            
            % Display the result
            set(app.ans, 'Text',strcat('Total Marks : ',num2str(noOfCorrectAnswers),'/', num2str(totalNumberOfQuestions)))
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            app.UIAxes.Visible=false;
            cla(app.UIAxes2,'reset')
            app.UIAxes2.Visible=false;
            set(app.ans, 'Text','Answers:');
            app.TotalNumberOfQuestions.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MCQANSWERSHEETMARKINGSYSTEMLabel = uilabel(app.UIFigure);
            app.MCQANSWERSHEETMARKINGSYSTEMLabel.Text = 'MCQ ANSWER SHEET MARKING SYSTEM';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Referance Answer Sheet')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Input Answer Sheet')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.SelectReferanceAnswerSheetButton = uibutton(app.UIFigure, 'push');
            app.SelectReferanceAnswerSheetButton.ButtonPushedFcn = createCallbackFcn(app, @select, true);
            app.SelectReferanceAnswerSheetButton.Text = 'Select Referance Answer Sheet';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GiveAnswerButton = uibutton(app.UIFigure, 'push');
            app.GiveAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @GiveAnswerButtonPushed, true);
            app.GiveAnswerButton.Text = 'Give Answer';
            app.TotalNumberOfQuestionsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNumberOfQuestionsEditFieldLabel.Text = 'Total Number Of Questions: ';
            app.TotalNumberOfQuestions = uieditfield(app.UIFigure, 'numeric');
            app.SelectAnswerSheetButton = uibutton(app.UIFigure, 'push');
            app.SelectAnswerSheetButton.ButtonPushedFcn = createCallbackFcn(app, @select_sheet, true);
            app.SelectAnswerSheetButton.Text = 'Select Answer Sheet';
            app.ans = uilabel(app.UIFigure);
            app.ans.Text = 'Answers:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end